classdef simuPACT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Plotthefirst2sofwithandwihtoutPACCheckBox  matlab.ui.control.CheckBox
        VarratiodBofwithoutPACEditField  matlab.ui.control.NumericEditField
        VarratioofthewithoutPACLabel    matlab.ui.control.Label
        Plotthefirst2sofsignalandnoiseCheckBox  matlab.ui.control.CheckBox
        ProcessingwindowsGauge          matlab.ui.control.LinearGauge
        ProcessingwindowsGaugeLabel     matlab.ui.control.Label
        CalculateEmpiricallyNormalizedMIslowCheckBox  matlab.ui.control.CheckBox
        ExporttoWorkspaceButton         matlab.ui.control.Button
        SNRsdBtoexploreEditField        matlab.ui.control.EditField
        SNRsdBtoexploreEditFieldLabel   matlab.ui.control.Label
        ProcessingeachSNRlevelGauge     matlab.ui.control.LinearGauge
        ProcessingeachSNRlevelGaugeLabel  matlab.ui.control.Label
        GeneratingrealizationsforeachSNRGauge  matlab.ui.control.LinearGauge
        ProgressLabel                   matlab.ui.control.Label
        HFOfreqrangeloHzhiHzEditField   matlab.ui.control.EditField
        HFOfreqrangeloHzhiHzEditFieldLabel  matlab.ui.control.Label
        LFOcenterfreq01octfluctuationaddedEditField  matlab.ui.control.EditField
        LFOcenterfreq01octfluctuationaddedEditFieldLabel  matlab.ui.control.Label
        WelcometosimuPACLabel           matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        SwitchtoDropDown                matlab.ui.control.DropDown
        SwitchtoDropDownLabel           matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        NumwindowsperSNREditField       matlab.ui.control.NumericEditField
        NumwindowsperSNREditFieldLabel  matlab.ui.control.Label
        WindowlengthsEditField          matlab.ui.control.NumericEditField
        WindowlengthsEditFieldLabel     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function startButton(app, event)


            %%%%%%%%%%%%%%%%
            %%% History. %%%
            %%%%%%%%%%%%%%%%
            % 12/19/2024 Makoto. Stats added.
            % 08/21/2024 Makoto. Created.


            % Add path to the colored noise generator by Hristo Zhivomirov.
            pathToThisMlapp = which('simuPACT.mlapp');
            filesepIdx = strfind(pathToThisMlapp, filesep);
            addpath(genpath([pathToThisMlapp(1:filesepIdx(end)) 'external']));


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Obtain user-input paramters. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            phaseLowFreqHz = str2num(app.LFOcenterfreq01octfluctuationaddedEditField.Value);
            ampHighFreqHz  = str2num(app.HFOfreqrangeloHzhiHzEditField.Value);
            SNR_dB         = str2num(app.SNRsdBtoexploreEditField.Value);
            windowLengthS  = app.WindowlengthsEditField.Value;
            numWindows     = app.NumwindowsperSNREditField.Value;
            if mod(numWindows,2)~=0
                error('The number of iteration must be even numbers.')
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Check the validity of the choice of the high-frequency range. Taken from Oezkurt and Schinitzler (2011) %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            omega_H      = mean(ampHighFreqHz);
            omega_L      = mean(phaseLowFreqHz);
            Deltaomega_L = diff(phaseLowFreqHz);
            Deltaomega_H = diff(ampHighFreqHz);
            if omega_H <= omega_L + Deltaomega_L + Deltaomega_H
                errordlg(sprintf('The HFO freq range is too low. Its lower edge must be > %.1f Hz.', omega_L + Deltaomega_L + Deltaomega_H))
                return
            end


            % Set the sampling rate.
            EEG.srate = 1000;

            % Data length in frame.
            dataLengthInSec = windowLengthS*numWindows*2; % Half for signal, half for noise.


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Reset the axes and meters. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cla(app.UIAxes)
            cla(app.UIAxes2)
            if length(SNR_dB)==1
                app.GeneratingrealizationsforeachSNRGauge.Enable = "off";
            elseif length(SNR_dB)>1
                app.GeneratingrealizationsforeachSNRGauge.Enable = "on";
                app.GeneratingrealizationsforeachSNRGauge.Value  = min(SNR_dB);
                app.GeneratingrealizationsforeachSNRGauge.Limits = [min(SNR_dB) max(SNR_dB)];
                app.GeneratingrealizationsforeachSNRGauge.MajorTicks = SNR_dB;
                app.GeneratingrealizationsforeachSNRGauge.MinorTicks = [];
            end

            app.ProcessingwindowsGauge.Value       = 0;
            app.ProcessingwindowsGauge.Limits      = [0 numWindows];
            app.ProcessingwindowsGauge.MajorTicks  = [0 round(numWindows/4) round(numWindows/2) round(numWindows/4*3) numWindows];
            app.ProcessingwindowsGauge.MinorTicks  = [];

            if length(SNR_dB)==1
                app.ProcessingeachSNRlevelGauge.Enable = "off";
            elseif length(SNR_dB)>1
                app.ProcessingeachSNRlevelGauge.Enable = "on";
                app.ProcessingeachSNRlevelGauge.Value      = min(SNR_dB);
                app.ProcessingeachSNRlevelGauge.Limits     = [min(SNR_dB) max(SNR_dB)];
                app.ProcessingeachSNRlevelGauge.MajorTicks = SNR_dB;
                app.ProcessingeachSNRlevelGauge.MinorTicks = [];
            end

            drawnow


            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Set the target SNR. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Convert SNR from dB to a linear scale
            SNR_linear = 10.^(SNR_dB / 10);

            % Calculate the noise variance for each SNR value
            % SNR_linear = signal_variance / noise_variance
            % Rearrange to get noise_variance = signal_variance / SNR_linear
            signalVar   = 1;
            noiseVarVec = signalVar ./ SNR_linear;
            %{
                % Display the noise variance corresponding to each SNR
                disp('SNR (dB) and Corresponding Noise Variance:')
                for i = 1:length(SNR_dB)
                    fprintf('SNR = %d dB, Noise Variance = %.4f\n', SNR_dB(i), noiseVarVec(i));
                end
            %}


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Set band-pass filter parameters for low-freq (LF) and high-freq (HF) bands. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            phaseTransitionBandWidth = min([phaseLowFreqHz/2 1]);
            phaseFilterOrder = pop_firwsord('hamming', EEG.srate, phaseTransitionBandWidth);

            fprintf('\n\n')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            fprintf('Bandpass filter parameters for low-frequency phase:\n')
            fprintf('Filter type: Zero-phase FIR filter (Hamming window)\n')
            fprintf('Filter order: %.0f (Sampling rate: %.0f [Hz])', phaseFilterOrder, EEG.srate)
            fprintf('Pass-band edges: %.2f to %.2f [Hz]\n', phaseLowFreqHz-phaseTransitionBandWidth/2, phaseLowFreqHz+phaseTransitionBandWidth/2)
            fprintf('Cutoff (-6dB) frequencies: %.2f to %.2f [Hz]\n', phaseLowFreqHz-phaseTransitionBandWidth/2, phaseLowFreqHz+phaseTransitionBandWidth/2)
            fprintf('Transition band width: %.2f [Hz]\n', phaseTransitionBandWidth)
            fprintf('\n\n')

            ampTransitionBandWidth = min([ampHighFreqHz(1)*0.1 diff(ampHighFreqHz)]);
            ampFilterOrder = pop_firwsord('hamming', EEG.srate, ampTransitionBandWidth);

            fprintf('Bandpass filter parameters for high-frequency amplitude:\n')
            fprintf('Filter type: Zero-phase FIR filter (Hamming window)\n')
            fprintf('Filter order: %.0f (Sampling rate: %.0f [Hz])', ampFilterOrder, EEG.srate)
            fprintf('Pass-band edges: %.2f to %.2f [Hz]\n', ampHighFreqHz(1)-ampTransitionBandWidth/2, ampHighFreqHz(2)+ampTransitionBandWidth/2)
            fprintf('Cutoff (-6dB) frequencies: %.2f to %.2f [Hz]\n', ampHighFreqHz(1), ampHighFreqHz(2))
            fprintf('Transition band width: %.2f [Hz]\n', ampTransitionBandWidth)
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            fprintf('\n\n')


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Check the window length. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            upperEdgeOfLfoHz = phaseLowFreqHz(1);
            minimumWinLength = 1/upperEdgeOfLfoHz;
            if windowLengthS < minimumWinLength
                errordlg(sprintf('The window length is too short. It must be > %.1f s.', ceil(minimumWinLength*10)/10))
                return
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Generate data for as many as SNR_dB and for [numWindows] repetition. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            realizationMatrix = zeros(length(SNR_dB), dataLengthInSec*EEG.srate);
            SNR_HFO_Vec       = zeros(size(realizationMatrix,1),1);
            SNR_broadband_Vec = zeros(size(realizationMatrix,1),1);
            timeElapsedVec    = nan(length(noiseVarVec),1);
            for snrIdx = 1:length(noiseVarVec)

                % Start the timer.
                startedTime = tic;

                % Generate simulated signal and matched noise. Note that
                % 1. var(matchedNoise)==1.
                % 2. The variances within the LFO and HFO ranges are matched between LFO_HFO and matchedNoise.
                % 3. The power ratio of LFO:HFO is determined by the PSD of the pink noise.
                [LFO_HFO, matchedNoise] = Oezkurt2011_synthesizePAC(phaseLowFreqHz, ampHighFreqHz, dataLengthInSec/2);
                %{
                        figure
                        subplot(1,2,1)
                        plot(sum(LFO_HFO,1))
                        hold on
                        plot(matchedNoise)
                        xlim([0 3000])
                        subplot(1,2,2)
                        plot(sum(LFO_HFO,1) + matchedNoise)
                        hold on
                        plot(matchedNoise)
                        xlim([0 3000])
                %}

                % Generate additive noise. This determines SNR.
                additiveNoise = pinknoise(dataLengthInSec/2*EEG.srate);

                % Band-pass filter to extract HFO.
                additiveNoise_HFO = bandpassFilter(additiveNoise, ampHighFreqHz, ampFilterOrder);

                % Calculate high-freq range variance of signal and noise. 
                varSignal        = var(LFO_HFO(2,:)); % Variance of HF (GLM requires matched amp variance!)
                varAdditiveNoise = var(additiveNoise_HFO);

                % Calculate target signal variance for desired SNR
                targetVarSignal = varAdditiveNoise * SNR_linear(snrIdx);

                % Calculate scaling factor
                alpha = sqrt(targetVarSignal / varSignal);

                % Scale lfoHfoCombined
                scaleMatchedLfoHfo = LFO_HFO * alpha;

                % Calculate the actual SNR.
                SNR_HFO       = 10*log10(var(scaleMatchedLfoHfo(2,:))/var(additiveNoise_HFO));
                SNR_broadband = 10*log10(var(sum(scaleMatchedLfoHfo,1))/var(additiveNoise));
                SNR_HFO_Vec(      snrIdx) = SNR_HFO;
                SNR_broadband_Vec(snrIdx) = SNR_broadband;

                % Make the latter half of the data phase-amplitude randomized and paste it to the first half.
                signalBlock = sum(scaleMatchedLfoHfo,1) + additiveNoise;
                noiseBlock  = matchedNoise              + additiveNoise;

                % Calculate scaling factor to adjust the variance of noise_HFO to match varSignal
                signalBlock_HFO = bandpassFilter(signalBlock, ampHighFreqHz, ampFilterOrder);
                noiseBlock_HFO  = bandpassFilter(noiseBlock,  ampHighFreqHz, ampFilterOrder);
                varSignal = var(signalBlock_HFO); % Variance of HF (GLM requires matched amp variance!) <- This does not work after all. GLM inputs must have EXACT same var to make it work ax expected (08/19/2024)
                varNoise  = var(noiseBlock_HFO);
                alpha = sqrt(varSignal / varNoise);

                % Scale noise_HFO to match the variance of signal_HFO
                noiseBlock_matchedHfVar = noiseBlock * alpha;
                %{
                        figure
                        plot(signalBlock(1:3000))
                        hold on
                        plot(noiseBlock_matchedHfVar(1:3000))
                %}

                %{
                        % Confirm the same HF var.
                        EEG.data   = noiseBlock_matchedHfVar;
                        EEG_HFO    = pop_firws(EEG, 'fcutoff',  ampHighFreqHz,  'ftype', 'bandpass', 'wtype', 'hamming', 'forder', ampFilterOrder,   'minphase', 0);
                        varSignal              = var(signalBlock_HFO); % Variance of HF (GLM requires matched amp variance!)
                        varNoise_matchedHfVar  = var(EEG_HFO.data);
                %}

                % Calculate the actual SNR.
                SNR_HFO       = 10*log10(var(    scaleMatchedLfoHfo(2,:))/var(additiveNoise_HFO));
                SNR_broadband = 10*log10(var(sum(scaleMatchedLfoHfo,1))  /var(additiveNoise));
                SNR_HFO_Vec(      snrIdx) = SNR_HFO;
                SNR_broadband_Vec(snrIdx) = SNR_broadband;


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Apply the weight factor to the control data with no PAC. %%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                noiseWeightFactorDB    = app.VarratiodBofwithoutPACEditField.Value;
                amplitudeScalingFactor = 10^(noiseWeightFactorDB/20);
                noiseBlock_weighted = noiseBlock_matchedHfVar*amplitudeScalingFactor;
                        
                % Combine the data.
                combinedData = [noiseBlock_weighted signalBlock];

                % Store the data.
                realizationMatrix(snrIdx,:) = combinedData;

                % Update the GUI meter.
                app.GeneratingrealizationsforeachSNRGauge.Value = SNR_dB(snrIdx);
                drawnow


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Show the first 2 seconds of Signal vs. Noise time series. %%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.Plotthefirst2sofsignalandnoiseCheckBox.Value == 1
                    if snrIdx == 1
                        additiveNoiseFigHandle = figure('color', [0.93 0.96 1]);
                        tiledlayout('flow')
                    end
                    
                    lfoHfo_2s        = sum(scaleMatchedLfoHfo(:, 1:EEG.srate*2),1);
                    additiveNoise_2s = additiveNoise(1:EEG.srate*2);

                    lfoHfo_2s_demean        = lfoHfo_2s-mean(lfoHfo_2s);
                    additiveNoise_2s_demean = additiveNoise_2s-mean(additiveNoise_2s);

                    figure(additiveNoiseFigHandle)
                    nexttile
                    signalHandle = plot([1:EEG.srate*2]/1000, lfoHfo_2s_demean);
                    hold on
                    noiseHandle  = plot([1:EEG.srate*2]/1000, additiveNoise_2s_demean);
                    uistack(signalHandle, "top")
                    xlabel('Time (s)')
                    ylabel('Amplitude (a.u.)')
                    legend([signalHandle noiseHandle], {'PAC signal', 'Additive noise'}, 'location', 'best')
                    title(sprintf('SNR %.1f (dB)', SNR_dB(snrIdx)))
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Show the first 2 seconds of PAC Present vs. Absent. %%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.Plotthefirst2sofwithandwihtoutPACCheckBox.Value == 1
                    if snrIdx == 1
                        pacPresenceFigHandle = figure('color', [0.93 0.96 1]);
                        tiledlayout('flow')
                    end
                    
                    defaultColors = get(groot, 'defaultAxesColorOrder');

                    pacPresent_2s = signalBlock(        1:EEG.srate*2);
                    pacAbsent_2s  = noiseBlock_weighted(1:EEG.srate*2);

                    pacPresent_2s_demean = pacPresent_2s-mean(pacPresent_2s);
                    pacAbsent_2s_demean  = pacAbsent_2s -mean(pacAbsent_2s);

                    figure(pacPresenceFigHandle)
                    nexttile
                    signalHandle = plot([1:EEG.srate*2]/1000, pacPresent_2s_demean, 'color', defaultColors(3,:));
                    hold on
                    noiseHandle  = plot([1:EEG.srate*2]/1000, pacAbsent_2s_demean,  'color', defaultColors(4,:));
                    uistack(signalHandle, "top")
                    xlabel('Time (s)')
                    ylabel('Amplitude (a.u.)')
                    legend([signalHandle noiseHandle], {'PAC Signal + Additive Noise', 'PAC Absent'}, 'location', 'best')
                    title(sprintf('SNR %.1f (dB), PAC Absent''s variance %.1f dB', SNR_dB(snrIdx), noiseWeightFactorDB))
                end
            end

                %{
                currentData         = realizationMatrix(1,:);
            
                figure
                plot(currentData)
            
                currentData_noPAC   = currentData(1:length(currentData)/2);
                currentData_withPAC = currentData(length(currentData)/2+1:end);
                
                figure
                subplot(1,2,1)
                plot(currentData_noPAC(1:1000))
                subplot(1,2,2)
                plot(currentData_withPAC(1:1000))
                
                EEG2 = EEG;
                EEG2.data = currentData;
                EEG2.pnts = length(currentData);
                ampEEG      = pop_firws(EEG2, 'fcutoff',  [ampHighFreqHz(1)  ampHighFreqHz(2)],  'ftype', 'bandpass', 'wtype', 'hamming', 'forder', ampFilterOrder, 'minphase', 0);
                
                figure
                subplot(1,2,1)
                plot(ampEEG.data(1, 1:1000))
                subplot(1,2,2)
                plot(ampEEG.data(1, length(currentData)/2+1:length(currentData)/2+1000))
                %}


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Obtain window center time %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            windowCenterTimesS = windowLengthS/2:windowLengthS:dataLengthInSec; % No overlap.

            % Make sure eventTimeSec is a column vector.
            if size(windowCenterTimesS,2)>size(windowCenterTimesS,1)
                windowCenterTimesS = windowCenterTimesS';
            end

            % Check the window coverage.
            windowCenterTimesS = sort(windowCenterTimesS);
            currentWindowFrameEdges      = [(windowCenterTimesS-windowLengthS/2) (windowCenterTimesS+windowLengthS/2)]*EEG.srate;
            currentWindowFrameEdges(:,1) = currentWindowFrameEdges(:,1)+1;


            %%%%%%%%%%%%%%%%%%%%%%
            %%% Calculate PAC. %%%
            %%%%%%%%%%%%%%%%%%%%%%
            numPhaseBinsPerPi = 9; % Tort et al. (2010) used 9 (i.e. 18 bins for 2pi).
            canoltysMiAllSNRs  = nan(length(SNR_dB), length(windowCenterTimesS));
            dirEstMiAllSNRs    = nan(length(SNR_dB), length(windowCenterTimesS));
            normMiAllSNRs      = nan(length(SNR_dB), length(windowCenterTimesS)); % Slow; not computed..
            klDistAllSNRs      = nan(length(SNR_dB), length(windowCenterTimesS));
            standardGlmAllSNRs = nan(length(SNR_dB), length(windowCenterTimesS));
            equivToGlmAllSNRs  = nan(length(SNR_dB), length(windowCenterTimesS));
            robustGlmAllSNRs   = nan(length(SNR_dB), length(windowCenterTimesS));

            for snrIdx = 1:size(realizationMatrix,1)

                % Select the current channel. This is to use pop_firws.
                currentSnrData = realizationMatrix(snrIdx, :);

                % Extract low-frequency phase.
                currentSnrData_LF = bandpassFilter(currentSnrData, [phaseLowFreqHz-phaseTransitionBandWidth/2 phaseLowFreqHz+phaseTransitionBandWidth/2], phaseFilterOrder);
                analyticPhase     = angle(hilbert(currentSnrData_LF'));

                % Extract high-frequency amplitude.
                currentSnrData_HF = bandpassFilter(currentSnrData, ampHighFreqHz, ampFilterOrder);
                analyticAmp       = abs(hilbert(currentSnrData_HF'));


                % Compute low-freq phase coupled with high-freq amp.
                Z = analyticAmp.* exp(1i*analyticPhase);

                for windowIdx = 1:length(windowCenterTimesS)

                    % Prepare the current window phase, amp, and Z.
                    currentWinPhase = analyticPhase(currentWindowFrameEdges(windowIdx,1):currentWindowFrameEdges(windowIdx,2));
                    currentWinAmp   = analyticAmp(  currentWindowFrameEdges(windowIdx,1):currentWindowFrameEdges(windowIdx,2));
                    currentWinZ     = Z(            currentWindowFrameEdges(windowIdx,1):currentWindowFrameEdges(windowIdx,2));


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Modulation Index (MI). (Canolty et al., 2006) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    canoltysMI = abs(mean(currentWinZ));


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Direct-Estimate Modulation Index (DEMI). (Oezkurt and Schnitzler, 2011) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    directEstimateMI = (1./sqrt(length(currentWinZ))) * abs(mean(currentWinZ)) / sqrt(mean(analyticAmp.*analyticAmp));


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Normalized Modulation Index (normMi). (Oezkurt and Schnitzler, 2011; Canolty et al., 2006 %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.CalculateEmpiricallyNormalizedMIslowCheckBox.Value == 1
                        numSurroIter = 200; % Per Oezkurt and Schnitzler (2011).
                        surroAmpMatrix = zeros(length(currentWinZ), numSurroIter);
                        for surroIdx = 1:numSurroIter
                            ampRandomizingIdx = randperm(length(currentWinAmp));
                            currentWinAmpRandomized = currentWinAmp(ampRandomizingIdx);
                            surroAmpMatrix(:,surroIdx) = currentWinAmpRandomized;
                        end
                        currentWinPhaseMatrix = repmat(currentWinPhase, [1 numSurroIter]);
                        surroMi = abs(mean(surroAmpMatrix.*exp(1i*currentWinPhaseMatrix),1));
                        [surroMiMean, surroMiStd] = normfit(surroMi);
                        normMi = (canoltysMI - surroMiMean)/surroMiStd; % Converting to a z-score.

                    else
                        normMi = nan;
                    end


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Kullback-Leibler divergence. (Tort et al., 2010) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    phaseBins = -pi:pi/numPhaseBinsPerPi:pi;
                    [~, freqBinIdx] = histc(currentWinPhase, phaseBins);
                    ampDistOverPhaseBins = zeros(1,length(phaseBins)-1);
                    for phaseBinIdx = 1:length(phaseBins)-1
                        currentBinIdx = find(freqBinIdx == phaseBinIdx);
                        if any(currentBinIdx)
                            ampDistOverPhaseBins(phaseBinIdx) = mean(currentWinAmp(currentBinIdx));
                        else
                            ampDistOverPhaseBins(phaseBinIdx) = 0;
                        end
                    end
                    ampDistOverPhaseBins = ampDistOverPhaseBins/sum(ampDistOverPhaseBins);
                    klDistance = KLDiv(ampDistOverPhaseBins, ones(1, length(ampDistOverPhaseBins))/length(ampDistOverPhaseBins));


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Standard GLM. (Penny et al., 2008) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Taken from Oezkurt et al. (2011)
                    X = [cos(currentWinPhase) sin(currentWinPhase) ones(length(currentWinZ),1)];
                    [beta_coef, redundant_term, error_trms] = regress(currentWinAmp, X);
                    standardGLM = sqrt( (sum(currentWinAmp.*currentWinAmp) - sum(error_trms.*error_trms)) / sum(currentWinAmp.*currentWinAmp) );

                    % When variances of the input data are not exactly the same, GLM-based approach will FAIL.
                    %
                    % currentWinAmp = zscore(currentWinAmp);
                    % [beta_coef, redundant_term, error_trms] = regress(currentWinAmp, X);
                    % standardGLM = sqrt( (sum(currentWinAmp.*currentWinAmp) - sum(error_trms.*error_trms)) / sum(currentWinAmp.*currentWinAmp) );

                    %{
                            % The first epoch of noiseBlock
                            firstWinPhase = analyticPhase(currentWindowFrameEdges(1,1):currentWindowFrameEdges(1,2));
                            firstWinAmp   = analyticAmp(  currentWindowFrameEdges(1,1):currentWindowFrameEdges(1,2));
                            firstWinZ     = Z(            currentWindowFrameEdges(1,1):currentWindowFrameEdges(1,2));
                
                            X = [cos( firstWinPhase) sin( firstWinPhase) ones(length( firstWinZ),1)];
                            [beta_coef1, redundant_term1, error_trms1] = regress(firstWinAmp, X);
                            var(firstWinAmp)
                            var(error_trms1)
                
                            % The first epoch of signalBlock.
                            latterHalfWinPhase = analyticPhase(currentWindowFrameEdges(length(eventTimesSec)/2+1,1):currentWindowFrameEdges(length(eventTimesSec)/2+1,2));
                            latterHalfWinAmp   = analyticAmp(  currentWindowFrameEdges(length(eventTimesSec)/2+1,1):currentWindowFrameEdges(length(eventTimesSec)/2+1,2));
                            latterHalfWinZ     = Z(            currentWindowFrameEdges(length(eventTimesSec)/2+1,1):currentWindowFrameEdges(length(eventTimesSec)/2+1,2));
                
                            X = [cos(latterHalfWinPhase) sin(latterHalfWinPhase) ones(length(latterHalfWinZ),1)];
                            [beta_coef2, redundant_term2, error_trms2] = regress(latterHalfWinAmp, X);
                            var(latterHalfWinAmp)
                            var(error_trms2)
                
                            % Comparison_afterNormalization.
                            firstWinAmpZ      = zscore(firstWinAmp);
                            latterHalfWinAmpZ = zscore(latterHalfWinAmp);
                
                            X = [cos( firstWinPhase) sin( firstWinPhase) ones(length( firstWinZ),1)];
                            [beta_coef3, redundant_term3, error_trms3] = regress(firstWinAmpZ, X);
                
                            X = [cos( latterHalfWinPhase) sin( latterHalfWinPhase) ones(length( latterHalfWinZ),1)];
                            [beta_coef4, redundant_term4, error_trms4] = regress(latterHalfWinAmpZ, X);
                
                            var(firstWinAmpZ)
                            var(latterHalfWinAmpZ)
                
                            var(error_trms3)
                            var(error_trms4)
                            
                            standardGLM_noise  = sqrt( (sum(firstWinAmpZ.*firstWinAmpZ)           - sum(error_trms3.*error_trms3)) / sum(firstWinAmpZ.*firstWinAmpZ) );
                            standardGLM_signal = sqrt( (sum(latterHalfWinAmpZ.*latterHalfWinAmpZ) - sum(error_trms4.*error_trms4)) / sum(latterHalfWinAmpZ.*latterHalfWinAmpZ) );
                    %}


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Equivalent to GLM. (Oezkurt and Schnitzler, 2011) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    equivToGLM = sqrt ( length(currentWinZ) * (beta_coef(1)^2 + beta_coef(2)^2 + beta_coef(3)^2) / sum(currentWinAmp.*currentWinAmp)  );


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Robust GLM. (Oezkurt and Schnitzler, 2011) %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    robustGLM = 0.5 * sqrt ( (beta_coef(1)^2 + beta_coef(2)^2) / sum(currentWinAmp.*currentWinAmp)  );


                    %%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% Store the results. %%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%
                    canoltysMiAllSNRs(   snrIdx,windowIdx) = canoltysMI;
                    dirEstMiAllSNRs(     snrIdx,windowIdx) = directEstimateMI;
                    normMiAllSNRs(       snrIdx,windowIdx) = normMi;
                    klDistAllSNRs(       snrIdx,windowIdx) = klDistance;
                    standardGlmAllSNRs(  snrIdx,windowIdx) = standardGLM;
                    equivToGlmAllSNRs(   snrIdx,windowIdx) = equivToGLM;
                    robustGlmAllSNRs(    snrIdx,windowIdx) = robustGLM;

                    % Update GUI.
                    app.ProcessingwindowsGauge.Value = windowIdx;
                    drawnow
                end

                % Update GUI.
                app.ProcessingeachSNRlevelGauge.Value = SNR_dB(snrIdx);
                drawnow
            end


            %%%%%%%%%%%%%%%%%%%%%%%
            %%% Perform t-test. %%%
            %%%%%%%%%%%%%%%%%%%%%%%
            [~,P1,~,STATS1] = ttest2(canoltysMiAllSNRs( :,1:length(windowCenterTimesS)/2)', canoltysMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d1              = cohensd(canoltysMiAllSNRs(:,1:length(windowCenterTimesS)/2)', canoltysMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            [~,P2,~,STATS2] = ttest2(dirEstMiAllSNRs( :,1:length(windowCenterTimesS)/2)', dirEstMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d2              = cohensd(dirEstMiAllSNRs(:,1:length(windowCenterTimesS)/2)', dirEstMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            [~,P3,~,STATS3] = ttest2(normMiAllSNRs( :,1:length(windowCenterTimesS)/2)', normMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d3              = cohensd(normMiAllSNRs(:,1:length(windowCenterTimesS)/2)', normMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            [~,P4,~,STATS4] = ttest2(klDistAllSNRs( :,1:length(windowCenterTimesS)/2)', klDistAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d4              = cohensd(klDistAllSNRs(:,1:length(windowCenterTimesS)/2)', klDistAllSNRs(:,length(windowCenterTimesS)/2+1:end)');               
            [~,P5,~,STATS5] = ttest2(standardGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', standardGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d5              = cohensd(standardGlmAllSNRs(:,1:length(windowCenterTimesS)/2)', standardGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            [~,P6,~,STATS6] = ttest2(equivToGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', equivToGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d6              = cohensd(equivToGlmAllSNRs(:,1:length(windowCenterTimesS)/2)', equivToGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            [~,P7,~,STATS7] = ttest2(robustGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', robustGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            d7              = cohensd(robustGlmAllSNRs(:,1:length(windowCenterTimesS)/2)', robustGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            pVec = [P1; P2; P3; P4; P5; P6; P7];
            dVec = [d1; d2; d3; d4; d5; d6; d7];


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Pass the data to the plot button. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            resultStructure.canoltysMiAllSNRs  = canoltysMiAllSNRs;
            resultStructure.dirEstMiAllSNRs    = dirEstMiAllSNRs;
            resultStructure.normMiAllSNRs      = normMiAllSNRs;
            resultStructure.klDistAllSNRs      = klDistAllSNRs;
            resultStructure.standardGlmAllSNRs = standardGlmAllSNRs;
            resultStructure.equivToGlmAllSNRs  = equivToGlmAllSNRs;
            resultStructure.robustGlmAllSNRs   = robustGlmAllSNRs;
            resultStructure.SNR_HFO            = SNR_HFO_Vec;
            resultStructure.SNR_broadband_Vec  = SNR_broadband_Vec;
            resultStructure.pVec               = pVec;
            resultStructure.dVec               = dVec;
            
            setappdata(app.PlotButton, 'resultStructure', resultStructure)
            disp('Results stored.')


        %%
        function outputData = bandpassFilter(inputData, cutoffFreqsHz, filterOrder)

            % Create a dummy EEG data to use pop_firws(). The sampling rate is fixed to 1000 Hz.
            EEG = eeg_emptyset;
            EEG.setname = 'Dummy';
            EEG.subject = 'Dummy';
            EEG.nbchan  = 1;
            EEG.trials  = 1;
            EEG.pnts    = length(inputData);
            EEG.srate   = 1000;
            EEG.xmin    = 0.001;
            EEG.xmax    = EEG.pnts/1000;
            EEG.times   = 0.001:0.001:EEG.xmax;

            % Enter the current data.
            EEG.data    = inputData;

            % Apply the band-pass filter.
            EEG = pop_firws(EEG, 'fcutoff', cutoffFreqsHz,  'ftype', 'bandpass', 'wtype', 'hamming', 'forder', filterOrder, 'minphase', 0);

            % Prepare output.
            outputData = EEG.data;
        end


        %%
        function d = cohensd(x1, x2) % The input must be observations x conditions
            mean1 = mean(x1);
            mean2 = mean(x2);
            sd1   = std(x1);
            sd2   = std(x2);
            n1    = size(x1,1);
            n2    = size(x2,1);
            pooledSD = sqrt((repmat(n1-1, [1 size(x1,2)]).*sd1.^2 + repmat(n2-1, [1 size(x2,2)]).*sd2.^2) / (n1 + n2 - 2));
            d = abs((mean1 - mean2))./pooledSD;
        end


        %%
        function [lfoHfo, matchedNoise_HFnormalized] = Oezkurt2011_synthesizePAC(phaseLowFreqHz, ampHighFreqHz, dataLengthInSec)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Generate pink noise whose broadband variance is 1. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            EEG.srate    = 1000;
            dataLength   = EEG.srate*dataLengthInSec;
            matchedNoise = pinknoise(dataLength);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Rescale the pink noise so that the variance of HF is 1. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            phaseTransitionBandWidth = min([phaseLowFreqHz/2 1]);
            phaseFilterOrder = pop_firwsord('hamming', EEG.srate, phaseTransitionBandWidth);

            ampTransitionBandWidth = min([ampHighFreqHz(1)*0.1 diff(ampHighFreqHz)]);
            ampFilterOrder   = pop_firwsord('hamming', EEG.srate, ampTransitionBandWidth);

            matchedNoise_HF = bandpassFilter(matchedNoise, ampHighFreqHz, ampFilterOrder);
            hfVar_orig      = var(matchedNoise_HF);

            matchedNoise_HFnormalized = matchedNoise/sqrt(hfVar_orig);

            matchedNoise_LF = bandpassFilter(matchedNoise_HFnormalized, [phaseLowFreqHz-phaseTransitionBandWidth/2 phaseLowFreqHz+phaseTransitionBandWidth/2], phaseFilterOrder);
            matchedNoise_HF = bandpassFilter(matchedNoise_HFnormalized, ampHighFreqHz,  ampFilterOrder);

            lfVar = var(matchedNoise_LF);
            hfVar = var(matchedNoise_HF);

            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Start the process. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%

            % Set the amount of fluctuation in LFO.
            lfoFluctuation = 0.1; % Unit: Octave.

            % Generate HFO from white noise.
            whiteNoise    = randn(1,dataLengthInSec*EEG.srate);
            continuousHfo = bandpassFilter(whiteNoise, ampHighFreqHz,  ampFilterOrder);

            % Compute the number of cycles needed.
            deltaTime = 0.001;  % Delta = 1 ms.
            totalDataLengthInMs = dataLengthInSec*EEG.srate;
            oneCycleLengthInMs  = floor(EEG.srate/mean(phaseLowFreqHz));
            numCyclesNeeded     = ceil(totalDataLengthInMs/oneCycleLengthInMs);
            numCyclesNeeded     = ceil(numCyclesNeeded*1.1);

            % Generate simulated PAC cycle by cycle.
            simulatedPac = cell(numCyclesNeeded,1);
            for cycleIdx = 1:numCyclesNeeded

                % Generate sinunoidal LFO with center freq +/- lfoFluctuation [Hz].
                currentFreq = phaseLowFreqHz + rand()*lfoFluctuation*phaseLowFreqHz; % Center freq + fluctuation.
                currentLfo  = cos(2.0*pi*(0:deltaTime*currentFreq:1-deltaTime*currentFreq));

                % Identify the trough index with trough phase +/- 1/2 pi fluctuation.
                unwrappedPhase      = unwrap(angle(hilbert(currentLfo)));
                wholeTroughIdx      = find(((1-1/2)*pi < unwrappedPhase) & (unwrappedPhase < (1+1/2)*pi));
                quarterTroughLength = floor(length(wholeTroughIdx)/4);
                threeQuarterTroughLength = length(wholeTroughIdx)-quarterTroughLength;
                beginningIdx     = randi(quarterTroughLength);
                currentTroughIdx = wholeTroughIdx(beginningIdx:beginningIdx+threeQuarterTroughLength-1);

                % Obtain the HFO for the current cycle.
                currentHfoOnset   = randi(length(continuousHfo)-length(currentTroughIdx));
                currentHfo        = continuousHfo(currentHfoOnset:currentHfoOnset+length(currentTroughIdx)-1);
                currentHfoHanning = hanning(length(currentHfo))'.*currentHfo; % Hanning tapered.

                % Combine LFO and HFO.
                singlePacCycle = currentLfo;
                singlePacCycle(currentTroughIdx) = singlePacCycle(currentTroughIdx) + currentHfoHanning;

                % Store LFO and Pac.
                lfo{cycleIdx}          = currentLfo;
                simulatedPac{cycleIdx} = singlePacCycle;
            end

            % Concatenate the cycle-by-cycle PAC singal.
            lfo          = cat(2, lfo{:});
            simulatedPac = cat(2, simulatedPac{:});
            hfo          = simulatedPac-lfo;


            %%%%%%%%%%%%%%%%%%%%%%
            %%% Trim the data. %%%
            %%%%%%%%%%%%%%%%%%%%%%
            lfo = lfo(:,1:dataLengthInSec*1000);
            hfo = hfo(:,1:dataLengthInSec*1000);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Match the var of **LF-HF combined** signals with that of the noise. %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            lfo_norm    = lfo/sqrt(var(lfo));
            hfo_nonzero = hfo(abs(hfo)>0); % Exclude all the zero values from the variance calculation.
            hfo_norm    = hfo/sqrt(var(hfo_nonzero));
            lfo_matched = lfo_norm*sqrt(lfVar);
            hfo_matched = hfo_norm*sqrt(hfVar);
            lfoHfo      = cat(1, lfo_matched, hfo_matched);
        end
      
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            cla(app.UIAxes)
            cla(app.UIAxes2)

            % Retrieve the results.
            resultStructure = getappdata(app.PlotButton, 'resultStructure');  % Retrieve data from the figure
            
            % Select user-specified results for visualization.
            currentMetric = app.SwitchtoDropDown.Value;
            switch currentMetric
                case 'MI'
                    selectedResults = resultStructure.canoltysMiAllSNRs;
                    pVal = resultStructure.pVec(1,:);
                    dVal = resultStructure.dVec(1,:);
                case 'Direct estimate MI'
                    selectedResults = resultStructure.dirEstMiAllSNRs;
                    pVal = resultStructure.pVec(2,:);
                    dVal = resultStructure.dVec(2,:);
                case 'Empirically Normalized MI'
                    selectedResults = resultStructure.normMiAllSNRs;
                    pVal = resultStructure.pVec(3,:);
                    dVal = resultStructure.dVec(3,:);
                    if app.CalculateEmpiricallyNormalizedMIslowCheckBox.Value==0
                        warndlg('Empirically normalized MI not calculated.')
                        return
                    end

                case 'KL Divergence'
                    selectedResults = resultStructure.klDistAllSNRs;
                    pVal = resultStructure.pVec(4,:);
                    dVal = resultStructure.dVec(4,:);
                case 'Robust GLM'
                    selectedResults = resultStructure.robustGlmAllSNRs;
                    pVal = resultStructure.pVec(7,:);
                    dVal = resultStructure.dVec(7,:);
            end
            % [~,P1,~,STATS1] = ttest2(canoltysMiAllSNRs( :,1:length(windowCenterTimesS)/2)', canoltysMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P2,~,STATS2] = ttest2(dirEstMiAllSNRs( :,1:length(windowCenterTimesS)/2)', dirEstMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P3,~,STATS3] = ttest2(normMiAllSNRs( :,1:length(windowCenterTimesS)/2)', normMiAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P4,~,STATS4] = ttest2(klDistAllSNRs( :,1:length(windowCenterTimesS)/2)', klDistAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P5,~,STATS5] = ttest2(standardGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', standardGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P6,~,STATS6] = ttest2(equivToGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', equivToGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');
            % [~,P7,~,STATS7] = ttest2(robustGlmAllSNRs( :,1:length(windowCenterTimesS)/2)', robustGlmAllSNRs(:,length(windowCenterTimesS)/2+1:end)');

            % Separate data into pac absent and present.
            pacAbsent  = [selectedResults(:,1:size(selectedResults,2)/2)]';
            pacPresent = [selectedResults(:,size(selectedResults,2)/2+1:end)]';

            plotData{1} = pacAbsent;
            plotData{2} = pacPresent;

            SNR_dB = str2num(app.SNRsdBtoexploreEditField.Value);
            xticklabels = arrayfun(@num2str, SNR_dB, 'UniformOutput', false);
            dataLabels = {'Pac Absent' 'Pac Present'};

            % Plot the data.
            h = daviolinplot_modified(app.UIAxes, app, plotData, 'box',0,...
                'boxcolor','w','scatter',2,'jitter',1,'scattercolor','same',...
                'scattersize',10,'scatteralpha',0.7,'linkline',1,...
                'xtlabels', xticklabels,...
                'legend', dataLabels);
            %xl = xlim;
            %xlim(app.UIAxes, [xl(1)-0.1, xl(2)+0.3]); % make more space for the legend
            set(h.sc,'MarkerEdgeColor','none');      % remove marker edge color

            % Plot the stats.
            h2 = bar(app.UIAxes2, dVal, 0.6);
            set(h2, 'FaceColor', [0.8 0.8 0.8])
            xlim(app.UIAxes2, [0.6 length(dVal)+0.4])
            ylim(app.UIAxes2, [0 max(dVal)*1.3])
            set(app.UIAxes2, 'xtick', [])
            for barIdx = 1:length(dVal)
                text(app.UIAxes2, barIdx-0.2, dVal(barIdx)+max(dVal)/8, sprintf('p=%.4f', pVal(barIdx)))
            end
 
           
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            
            % Retrieve the results.
            resultStructure = getappdata(app.PlotButton, 'resultStructure');  % Retrieve data from the figure

            % Select user-specified results for visualization.
            currentMetric = app.SwitchtoDropDown.Value;
            switch currentMetric
                case 'MI'
                    selectedResults = resultStructure.canoltysMiAllSNRs;
                    savingVariableName = 'MI';

                case 'Direct estimate MI'
                    selectedResults = resultStructure.dirEstMiAllSNRs;
                    savingVariableName = 'directEstimateMI';

                case 'KL Divergence'
                    selectedResults = resultStructure.klDistAllSNRs;
                    savingVariableName = 'KLDivergence';

                case 'Robust GLM'
                    selectedResults = resultStructure.robustGlmAllSNRs;
                    savingVariableName = 'robustGLM';
            end

            % Separate data into pac absent and present.
            pacAbsent  = [selectedResults(:,1:size(selectedResults,2)/2)]';
            pacPresent = [selectedResults(:,size(selectedResults,2)/2+1:end)]';

            % Create a variable to annotate ata dimensions.
            dataDimentions = {'Windows' 'SNR'};
            SNR_dB = str2num(app.SNRsdBtoexploreEditField.Value);

            % Export to base Workspace.
            assignin("base", 'simuPACT_selectedMeasure',savingVariableName);
            assignin("base", 'simuPACT_pacAbsent',      pacAbsent);
            assignin("base", 'simuPACT_pacPresent',     pacPresent);
            assignin("base", 'simuPACT_dataDimentions', dataDimentions);
            assignin("base", 'simuPACT_SNR_dB',         SNR_dB);

            msgbox('The currently selected data are exported as simuPACT_xxxx.')


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {821, 821};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WindowlengthsEditFieldLabel = uilabel(app.LeftPanel);
            app.WindowlengthsEditFieldLabel.Text = 'Window length [s]';
            app.WindowlengthsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WindowlengthsEditField.Value = 30;
            app.NumwindowsperSNREditFieldLabel = uilabel(app.LeftPanel);
            app.NumwindowsperSNREditFieldLabel.Text = 'Num windows per SNR';
            app.NumwindowsperSNREditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumwindowsperSNREditField.Value = 50;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButton, true);
            app.StartButton.Text = 'Start';
            app.SwitchtoDropDownLabel = uilabel(app.LeftPanel);
            app.SwitchtoDropDownLabel.Text = 'Switch to';
            app.SwitchtoDropDown = uidropdown(app.LeftPanel);
            app.SwitchtoDropDown.Items = {'', 'MI', 'Direct estimate MI', 'Empirically Normalized MI', 'KL Divergence', 'Robust GLM'};
            app.SwitchtoDropDown.Value = 'MI';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WelcometosimuPACLabel = uilabel(app.LeftPanel);
            app.WelcometosimuPACLabel.Text = 'Welcome to simuPAC!';
            app.LFOcenterfreq01octfluctuationaddedEditFieldLabel = uilabel(app.LeftPanel);
            app.LFOcenterfreq01octfluctuationaddedEditFieldLabel.Text = {'LFO center freq'; '+/- 0.1 oct. fluctuation added '};
            app.LFOcenterfreq01octfluctuationaddedEditField = uieditfield(app.LeftPanel, 'text');
            app.LFOcenterfreq01octfluctuationaddedEditField.Value = '2';
            app.HFOfreqrangeloHzhiHzEditFieldLabel = uilabel(app.LeftPanel);
            app.HFOfreqrangeloHzhiHzEditFieldLabel.Text = 'HFO freq range [loHz hiHz]';
            app.HFOfreqrangeloHzhiHzEditField = uieditfield(app.LeftPanel, 'text');
            app.HFOfreqrangeloHzhiHzEditField.Value = '[75 85]';
            app.ProgressLabel = uilabel(app.LeftPanel);
            app.ProgressLabel.Text = 'Generating realizations for each SNR';
            app.GeneratingrealizationsforeachSNRGauge = uigauge(app.LeftPanel, 'linear');
            app.ProcessingeachSNRlevelGaugeLabel = uilabel(app.LeftPanel);
            app.ProcessingeachSNRlevelGaugeLabel.Text = 'Processing each SNR level';
            app.ProcessingeachSNRlevelGauge = uigauge(app.LeftPanel, 'linear');
            app.SNRsdBtoexploreEditFieldLabel = uilabel(app.LeftPanel);
            app.SNRsdBtoexploreEditFieldLabel.Text = 'SNRs [dB] to explore';
            app.SNRsdBtoexploreEditField = uieditfield(app.LeftPanel, 'text');
            app.SNRsdBtoexploreEditField.Value = '-4:2:2';
            app.ExporttoWorkspaceButton = uibutton(app.LeftPanel, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.CalculateEmpiricallyNormalizedMIslowCheckBox = uicheckbox(app.LeftPanel);
            app.CalculateEmpiricallyNormalizedMIslowCheckBox.Text = 'Calculate Empirically Normalized MI (slow)';
            app.ProcessingwindowsGaugeLabel = uilabel(app.LeftPanel);
            app.ProcessingwindowsGaugeLabel.Text = 'Processing windows';
            app.ProcessingwindowsGauge = uigauge(app.LeftPanel, 'linear');
            app.Plotthefirst2sofsignalandnoiseCheckBox = uicheckbox(app.LeftPanel);
            app.Plotthefirst2sofsignalandnoiseCheckBox.Text = 'Plot the first 2-s of signal and noise';
            app.Plotthefirst2sofsignalandnoiseCheckBox.Value = true;
            app.VarratioofthewithoutPACLabel = uilabel(app.LeftPanel);
            app.VarratioofthewithoutPACLabel.Text = 'Var ratio [dB] of ''without PAC''';
            app.VarratiodBofwithoutPACEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Plotthefirst2sofwithandwihtoutPACCheckBox = uicheckbox(app.LeftPanel);
            app.Plotthefirst2sofwithandwihtoutPACCheckBox.Text = 'Plot the first 2-s of with and wihtout PAC';
            app.Plotthefirst2sofwithandwihtoutPACCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'PAC Present vs. Absent across SNRs')
            xlabel(app.UIAxes, 'SNR (dB)')
            ylabel(app.UIAxes, 'PAC measure')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Cohen''s d')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simuPACT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end